classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        Button               matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        EPO4ControlGUILabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        updateTimer % Description
        car
    end
    
    methods (Access = private)
        
        function updateLoop(app)
            status = EPOCommunications('receive');
            server = evalin('base', 'server;');
            
            % Send it to NodeJS
            if server.isOpen
                server.conn.send(jsonencode(status));
            end
            
            % Plot it in this GUI
            plot(app.UIAxes, status.position, 100, 'r*');
        end
        
        function driveForward(app)
            driveForward(app.car);
%             evalin('base', 'car.velocity = 1;');
        end
        
        function driveBackward(app)
%             EPOCommunications('transmit', 'M100')
%             evalin('base', 'car.velocity = -1;');
        end
        
        function driveStop(app)
            brake(app.car);
%             EPOCommunications('transmit', 'M150')
%              evalin('base', 'car.velocity = 0;');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get car object from base workspace
            app.car = evalin('base', 'car');
%             server = evalin('base', 'server');
            % Setup timer for event loop
            app.updateTimer = timer();
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.Period = 0.1;
            app.updateTimer.TimerFcn = @(~,~) updateLoop(app);
            start(app.updateTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Closing the 
            % TODO: 1st stop timer, then delete
            delete(app.updateTimer);
            delete(app)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'uparrow'
                    driveForward(app);
                case 'downarrow'
                    driveBackward(app);
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case {'uparrow', 'downarrow'}
                    driveStop(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X [cm]')
            ylabel(app.UIAxes, 'Y [cm]')
            app.UIAxes.XLim = [0 600];
            app.UIAxes.YLim = [0 600];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '⬆';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '⬇';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '⬅';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '⮕';
            app.EPO4ControlGUILabel = uilabel(app.UIFigure);
            app.EPO4ControlGUILabel.Text = 'EPO 4 Control GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end